classdef urban_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        URBANUTMUIFigure           matlab.ui.Figure
        FileMenu                   matlab.ui.container.Menu
        Menu                       matlab.ui.container.Menu
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        TabGroup                   matlab.ui.container.TabGroup
        UTMTab                     matlab.ui.container.Tab
        UITable                    matlab.ui.control.Table
        UTMClassDropDownLabel      matlab.ui.control.Label
        UTMClassDropDown           matlab.ui.control.DropDown
        Button_2                   matlab.ui.control.Button
        LanesLabel                 matlab.ui.control.Label
        RunLaneInitFunctionButton  matlab.ui.control.Button
        Button                     matlab.ui.control.Button
        UASTab                     matlab.ui.container.Tab
        tbl_UAS                    matlab.ui.control.Table
        btn_addUAS                 matlab.ui.control.Button
        EditAgentFunctionButton    matlab.ui.control.Button
        btn_refreshUAS             matlab.ui.control.Button
        tbl_UASProps               matlab.ui.control.Table
        UASConfigurationLabel      matlab.ui.control.Label
        UASAgentPropertiesLabel    matlab.ui.control.Label
        txt_UASHelp                matlab.ui.control.TextArea
        txt_UASPropHelp            matlab.ui.control.TextArea
        PerceptsTab                matlab.ui.container.Tab
        PerceptClassDropDownLabel  matlab.ui.control.Label
        PerceptClassDropDown       matlab.ui.control.DropDown
        btn_addPercept             matlab.ui.control.Button
        EditPerceptClassButton     matlab.ui.control.Button
        btn_refreshUAS_2           matlab.ui.control.Button
        tbl_PerceptProps           matlab.ui.control.Table
        tbl_PerceptMethods         matlab.ui.control.Table
        PerceptPropertiesLabel     matlab.ui.control.Label
        PerceptMethodsLabel        matlab.ui.control.Label
        txt_PerceptMethodHelp      matlab.ui.control.TextArea
        txt_PerceptPropHelp        matlab.ui.control.TextArea
        ActionClassDropDownLabel   matlab.ui.control.Label
        ActionClassDropDown        matlab.ui.control.DropDown
        btn_addAction              matlab.ui.control.Button
        ActionsTab                 matlab.ui.container.Tab
        tbl_ActionProps            matlab.ui.control.Table
        tbl_ActionMethods          matlab.ui.control.Table
        ActionPropertiesLabel_2    matlab.ui.control.Label
        ActionMethodsLabel         matlab.ui.control.Label
        btn_EditActionClass        matlab.ui.control.Button
        btn_refreshActionClasses   matlab.ui.control.Button
        txt_ActionPropHelp         matlab.ui.control.TextArea
        txt_ActionMethodHelp       matlab.ui.control.TextArea
        CenterPanel                matlab.ui.container.Panel
        UIAxes                     matlab.ui.control.UIAxes
        RightPanel                 matlab.ui.container.Panel
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = public)
        m_sim_runner % Description
        m_agent_files
        
        % UAS Properties
        m_uas_selected
        m_uas_prop_selected
        
        % Percept Properties
        m_percept_selected
        m_percept_prop_selected
        m_percept_method_selected
        
        % Action Properties
        m_action_selected
        m_action_prop_selected
        m_action_method_selected
        
        m_template = 'agents/NormalUAS.m'
        m_lanes
    end
    
    methods (Access = private)
        
        function updateUASTbl(app)
            app.m_agent_files = dir('agents/*.m');
            num_agent_types = length(app.m_agent_files);
            agent_names = {app.m_agent_files.name}';
            cats = categorical(agent_names);
            count = zeros(num_agent_types, 1);
            tdata = table(cats,count,'VariableNames',{'UAS_Type','Count'});
            app.tbl_UAS.Data = tdata;
        end
        
        function updateUASPropsTbl(app)
            app.tbl_UASProps.Enable = 'on';
            mc = meta.class.fromName(app.m_uas_selected);
            
            propNames = {};
            for i = 1:length(mc.PropertyList)
                propNames = [propNames; mc.PropertyList(i).Name];
            end
            
            tdata = table(propNames,'VariableNames',{'Property'});
            app.tbl_UASProps.Data = tdata;
        end
        
        function updatePerceptDropdown(app, reset_selection)
            percept_files = dir('percepts/*.m');
            percept_names = {percept_files.name}';
            for i = 1:length(percept_names)
                percept_names{i} = strrep(percept_names{i},'.m','');
            end

            app.PerceptClassDropDown.Items = percept_names;
            app.PerceptClassDropDown.Value = percept_names{1};
            if (reset_selection)
                app.m_percept_selected = percept_names{1};
            end
        end
        
        function updatePerceptPropsTbl(app)
            app.tbl_PerceptProps.Enable = 'on';
            mc = meta.class.fromName(app.m_percept_selected);
            
            propNames = {};
            for i = 1:length(mc.PropertyList)
                propNames = [propNames; mc.PropertyList(i).Name];
            end
            
            tdata = table(propNames,'VariableNames',{'Property'});
            app.tbl_PerceptProps.Data = tdata;
        end
        
        function updatePerceptMethodTbl(app)
            app.tbl_PerceptMethods.Enable = 'on';
            mc = meta.class.fromName(app.m_percept_selected);
            
            methodNames = {};
            for i = 1:length(mc.PropertyList)
                methodNames = [methodNames; mc.MethodList(i).Name];
            end
            
            tdata = table(methodNames,'VariableNames',{'Method'});
            app.tbl_PerceptMethods.Data = tdata;
        end
 
        function updateActionDropdown(app, reset_selection)
            action_files = dir('actions/*.m');
            action_names = {action_files.name}';
            for i = 1:length(action_names)
                action_names{i} = strrep(action_names{i},'.m','');
            end

            app.ActionClassDropDown.Items = action_names;
            app.ActionClassDropDown.Value = action_names{1};
            if (reset_selection)
                app.m_action_selected = action_names{1};
            end
        end
        
        function updateActionPropsTbl(app)
            app.tbl_ActionProps.Enable = 'on';
            mc = meta.class.fromName(app.m_action_selected);
            
            propNames = {};
            for i = 1:length(mc.PropertyList)
                propNames = [propNames; mc.PropertyList(i).Name];
            end
            
            tdata = table(propNames,'VariableNames',{'Property'});
            app.tbl_ActionProps.Data = tdata;
        end
        
        function updateActionMethodTbl(app)
            app.tbl_ActionMethods.Enable = 'on';
            mc = meta.class.fromName(app.m_action_selected);
            
            methodNames = {};
            for i = 1:length(mc.PropertyList)
                methodNames = [methodNames; mc.MethodList(i).Name];
            end
            
            tdata = table(methodNames,'VariableNames',{'Method'});
            app.tbl_ActionMethods.Data = tdata;
        end        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.updateUASTbl();
            
            app.EditAgentFunctionButton.Enable = false;
            
            % Initialize Percept Tab
            app.updatePerceptDropdown(true);
            app.updatePerceptPropsTbl();
            app.updatePerceptMethodTbl();
            
            % Initialize Action Tab
            app.updateActionDropdown(true);
            app.updateActionPropsTbl();
            app.updateActionMethodTbl();
            
            % Initialize the lanes
            app.m_lanes = ddas_system(5, 10);
            app.m_lanes.plotLanesQuiver(app.UIAxes);
            axis(app.UIAxes, 'equal');
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            roi = drawline(app.UIAxes);
        end

        % Button pushed function: btn_addUAS
        function btn_addUASButtonPushed(app, event)
            prompt = {'Enter Agent Type Name:'};
            dlgtitle = 'Input';
            dims = [1 35];
            definput = {'MyUAS'};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            if (~isempty(answer))
                agent_name = answer{1};
                agent_file_name = ['agents/' agent_name '.m'];
                %copyfile(app.m_template, agent_file_name);
                fid  = fopen(app.m_template,'r');
                f = fread(fid,'*char')';
                fclose(fid);
                f = strrep(f,'NormalUAS', agent_name);
                f = strrep(f,'MultiLaneUAS', 'NormalUAS');
                fid  = fopen(agent_file_name,'w');
                fprintf(fid,'%s',f);
                fclose(fid);
                app.updateUASTbl();
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.URBANUTMUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {804, 804, 804};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {804, 804};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {332, '1x', 270};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end

        % Cell selection callback: tbl_UAS
        function tbl_UASCellSelection(app, event)
            indices = event.Indices;
            c = cellstr(app.tbl_UAS.Data(indices(1),1).UAS_Type);
            app.m_uas_selected = strrep(c{1},'.m','');
            app.EditAgentFunctionButton.Enable = true;
            app.updateUASPropsTbl();
            
            % Update the Help text
            help_text = help(app.m_uas_selected);
            % Remove newlines
            %help_text = regexprep(help_text,'[\n\r]+','');
            % = regexprep(help_text,'[^\S\r\n]{2,}','');
            help_text = regexprep(help_text,'[ \t]{2,}','');
            help_text = regexprep(help_text,'[\n\r]','');
            % Trim the text
            help_text = strtrim(help_text);
            % Update the text
            app.txt_UASHelp.Value = help_text;
        end

        % Button pushed function: EditAgentFunctionButton
        function EditAgentFunctionButtonPushed(app, event)
            open([app.m_uas_selected '.m']);
        end

        % Button pushed function: btn_refreshUAS
        function btn_refreshUASButtonPushed(app, event)
            app.updateUASTbl();
        end

        % Value changed function: PerceptClassDropDown
        function PerceptClassDropDownValueChanged(app, event)
            value = app.PerceptClassDropDown.Value;
            app.m_percept_selected = value;
        end

        % Button pushed function: EditPerceptClassButton
        function EditPerceptClassButtonPushed(app, event)
            open(['percepts/' app.m_percept_selected '.m']);
        end

        % Button pushed function: btn_EditActionClass
        function btn_EditActionClassButtonPushed(app, event)
            open(['actions/' app.m_action_selected '.m']);
        end

        % Cell selection callback: tbl_PerceptProps
        function tbl_PerceptPropsCellSelection(app, event)
            indices = event.Indices;
            c = cellstr(app.tbl_PerceptProps.Data(indices(1),1).Property);
            app.m_percept_prop_selected = strrep(c{1},'.m','');
        
            % Update the Help text
            help_text = help([app.m_percept_selected '.' app.m_percept_prop_selected]);
            % Remove newlines
            %help_text = regexprep(help_text,'[\n\r]+','');
            % = regexprep(help_text,'[^\S\r\n]{2,}','');
            help_text = regexprep(help_text,'[ \t]{2,}','');
            help_text = regexprep(help_text,'[\n\r]','');
            % Trim the text
            help_text = strtrim(help_text);
            % Update the text
            app.txt_PerceptPropHelp.Value = help_text;
        end

        % Cell selection callback: tbl_PerceptMethods
        function tbl_PerceptMethodsCellSelection(app, event)
            indices = event.Indices;
            c = cellstr(app.tbl_PerceptMethods.Data(indices(1),1).Method);
            app.m_percept_method_selected = strrep(c{1},'.m','');
        
            % Update the Help text
            help_text = help([app.m_percept_selected '.' app.m_percept_method_selected]);
            % Remove newlines
            %help_text = regexprep(help_text,'[\n\r]+','');
            % = regexprep(help_text,'[^\S\r\n]{2,}','');
            help_text = regexprep(help_text,'[ \t]{2,}','');
            help_text = regexprep(help_text,'[\n\r]','');
            % Trim the text
            help_text = strtrim(help_text);
            % Update the text
            app.txt_PerceptMethodHelp.Value = help_text;
        end

        % Cell selection callback: tbl_ActionProps
        function tbl_ActionPropsCellSelection(app, event)
            indices = event.Indices;
            c = cellstr(app.tbl_ActionProps.Data(indices(1),1).Property);
            app.m_action_prop_selected = strrep(c{1},'.m','');
        
            % Update the Help text
            help_text = help([app.m_action_selected '.' app.m_action_prop_selected]);
            % Remove newlines
            %help_text = regexprep(help_text,'[\n\r]+','');
            % = regexprep(help_text,'[^\S\r\n]{2,}','');
            help_text = regexprep(help_text,'[ \t]{2,}','');
            help_text = regexprep(help_text,'[\n\r]','');
            % Trim the text
            help_text = strtrim(help_text);
            % Update the text
            app.txt_ActionPropHelp.Value = help_text;
        end

        % Cell selection callback: tbl_ActionMethods
        function tbl_ActionMethodsCellSelection(app, event)
            indices = event.Indices;
            c = cellstr(app.tbl_ActionMethods.Data(indices(1),1).Method);
            app.m_action_method_selected = strrep(c{1},'.m','');
        
            % Update the Help text
            help_text = help([app.m_action_selected '.' app.m_action_method_selected]);
            % Remove newlines
            %help_text = regexprep(help_text,'[\n\r]+','');
            % = regexprep(help_text,'[^\S\r\n]{2,}','');
            help_text = regexprep(help_text,'[ \t]{2,}','');
            help_text = regexprep(help_text,'[\n\r]','');
            % Trim the text
            help_text = strtrim(help_text);
            % Update the text
            app.txt_ActionMethodHelp.Value = help_text;
        end

        % Cell selection callback: tbl_UASProps
        function tbl_UASPropsCellSelection(app, event)
            indices = event.Indices;
            c = cellstr(app.tbl_UASProps.Data(indices(1),1).Property);
            app.m_uas_prop_selected = strrep(c{1},'.m','');
        
            % Update the Help text
            help_text = help([app.m_uas_selected '.' app.m_uas_prop_selected]);
            % Remove newlines
            %help_text = regexprep(help_text,'[\n\r]+','');
            % = regexprep(help_text,'[^\S\r\n]{2,}','');
            help_text = regexprep(help_text,'[ \t]{2,}','');
            help_text = regexprep(help_text,'[\n\r]','');
            % Trim the text
            help_text = strtrim(help_text);
            % Update the text
            app.txt_UASPropHelp.Value = help_text;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.URBANUTMUIFigure = uifigure('Visible', 'off');
            app.URBANUTMUIFigure.Name = 'URBAN UTM';
            app.URBANUTMUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.FileMenu = uimenu(app.URBANUTMUIFigure);
            app.FileMenu.Text = 'File';
            app.Menu = uimenu(app.FileMenu);
            app.Menu.Text = 'Menu';
            app.GridLayout = uigridlayout(app.URBANUTMUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.UTMTab = uitab(app.TabGroup);
            app.UTMTab.Title = 'UTM';
            app.Button = uibutton(app.UTMTab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Tooltip = {'Create a new lane'};
            app.Button.Text = '+';
            app.UITable = uitable(app.UTMTab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UTMClassDropDownLabel = uilabel(app.UTMTab);
            app.UTMClassDropDownLabel.Text = 'UTM Class';
            app.UTMClassDropDown = uidropdown(app.UTMTab);
            app.Button_2 = uibutton(app.UTMTab, 'push');
            app.Button_2.Tooltip = {'Create a new lane'};
            app.Button_2.Text = '-';
            app.LanesLabel = uilabel(app.UTMTab);
            app.LanesLabel.Text = 'Lanes';
            app.RunLaneInitFunctionButton = uibutton(app.UTMTab, 'push');
            app.RunLaneInitFunctionButton.Text = 'Run Lane Init Function';
            app.UASTab = uitab(app.TabGroup);
            app.UASTab.Title = 'UAS';
            app.tbl_UAS = uitable(app.UASTab);
            app.tbl_UAS.ColumnName = {'UAS_Type'; 'Count'};
            app.tbl_UAS.RowName = {};
            app.tbl_UAS.ColumnSortable = true;
            app.tbl_UAS.ColumnEditable = [true true];
            app.tbl_UAS.CellSelectionCallback = createCallbackFcn(app, @tbl_UASCellSelection, true);
            app.btn_addUAS = uibutton(app.UASTab, 'push');
            app.btn_addUAS.ButtonPushedFcn = createCallbackFcn(app, @btn_addUASButtonPushed, true);
            app.btn_addUAS.Tooltip = {'Create a new agent'};
            app.btn_addUAS.Text = '+';
            app.EditAgentFunctionButton = uibutton(app.UASTab, 'push');
            app.EditAgentFunctionButton.ButtonPushedFcn = createCallbackFcn(app, @EditAgentFunctionButtonPushed, true);
            app.EditAgentFunctionButton.Text = 'Edit Agent Function';
            app.btn_refreshUAS = uibutton(app.UASTab, 'push');
            app.btn_refreshUAS.ButtonPushedFcn = createCallbackFcn(app, @btn_refreshUASButtonPushed, true);
            app.btn_refreshUAS.Icon = 'icons8-refresh-24.png';
            app.btn_refreshUAS.Tooltip = {'Refresh the content in this table'};
            app.btn_refreshUAS.Text = '';
            app.tbl_UASProps = uitable(app.UASTab);
            app.tbl_UASProps.ColumnName = {'Property'};
            app.tbl_UASProps.RowName = {};
            app.tbl_UASProps.CellSelectionCallback = createCallbackFcn(app, @tbl_UASPropsCellSelection, true);
            app.tbl_UASProps.Enable = 'off';
            app.UASConfigurationLabel = uilabel(app.UASTab);
            app.UASConfigurationLabel.Text = 'UAS Configuration';
            app.UASAgentPropertiesLabel = uilabel(app.UASTab);
            app.UASAgentPropertiesLabel.Text = 'UAS Agent Properties';
            app.txt_UASHelp = uitextarea(app.UASTab);
            app.txt_UASHelp.Editable = 'off';
            app.txt_UASPropHelp = uitextarea(app.UASTab);
            app.txt_UASPropHelp.Editable = 'off';
            app.PerceptsTab = uitab(app.TabGroup);
            app.PerceptsTab.Title = 'Percepts';
            app.PerceptClassDropDownLabel = uilabel(app.PerceptsTab);
            app.PerceptClassDropDownLabel.Text = 'Percept Class';
            app.PerceptClassDropDown = uidropdown(app.PerceptsTab);
            app.PerceptClassDropDown.ValueChangedFcn = createCallbackFcn(app, @PerceptClassDropDownValueChanged, true);
            app.btn_addPercept = uibutton(app.PerceptsTab, 'push');
            app.btn_addPercept.Tooltip = {'Create a new agent'};
            app.btn_addPercept.Text = '+';
            app.EditPerceptClassButton = uibutton(app.PerceptsTab, 'push');
            app.EditPerceptClassButton.ButtonPushedFcn = createCallbackFcn(app, @EditPerceptClassButtonPushed, true);
            app.EditPerceptClassButton.Text = 'Edit Percept Class';
            app.btn_refreshUAS_2 = uibutton(app.PerceptsTab, 'push');
            app.btn_refreshUAS_2.Icon = 'icons8-refresh-24.png';
            app.btn_refreshUAS_2.Tooltip = {'Refresh the content in this tab'};
            app.btn_refreshUAS_2.Text = '';
            app.tbl_PerceptProps = uitable(app.PerceptsTab);
            app.tbl_PerceptProps.ColumnName = {'Property'};
            app.tbl_PerceptProps.RowName = {};
            app.tbl_PerceptProps.CellSelectionCallback = createCallbackFcn(app, @tbl_PerceptPropsCellSelection, true);
            app.tbl_PerceptMethods = uitable(app.PerceptsTab);
            app.tbl_PerceptMethods.ColumnName = {'Method'};
            app.tbl_PerceptMethods.RowName = {};
            app.tbl_PerceptMethods.CellSelectionCallback = createCallbackFcn(app, @tbl_PerceptMethodsCellSelection, true);
            app.PerceptPropertiesLabel = uilabel(app.PerceptsTab);
            app.PerceptPropertiesLabel.Text = 'Percept Properties';
            app.PerceptMethodsLabel = uilabel(app.PerceptsTab);
            app.PerceptMethodsLabel.Text = 'Percept Methods';
            app.txt_PerceptPropHelp = uitextarea(app.PerceptsTab);
            app.txt_PerceptPropHelp.Editable = 'off';
            app.txt_PerceptMethodHelp = uitextarea(app.PerceptsTab);
            app.txt_PerceptMethodHelp.Editable = 'off';
            app.ActionsTab = uitab(app.TabGroup);
            app.ActionsTab.Title = 'Actions';
            app.ActionClassDropDownLabel = uilabel(app.ActionsTab);
            app.ActionClassDropDownLabel.Text = 'Action Class';
            app.ActionClassDropDown = uidropdown(app.ActionsTab);
            app.btn_addAction = uibutton(app.ActionsTab, 'push');
            app.btn_addAction.Tooltip = {'Create a new agent'};
            app.btn_addAction.Text = '+';
            app.tbl_ActionProps = uitable(app.ActionsTab);
            app.tbl_ActionProps.ColumnName = {'Property'};
            app.tbl_ActionProps.RowName = {};
            app.tbl_ActionProps.CellSelectionCallback = createCallbackFcn(app, @tbl_ActionPropsCellSelection, true);
            app.tbl_ActionMethods = uitable(app.ActionsTab);
            app.tbl_ActionMethods.ColumnName = {'Method'};
            app.tbl_ActionMethods.RowName = {};
            app.tbl_ActionMethods.CellSelectionCallback = createCallbackFcn(app, @tbl_ActionMethodsCellSelection, true);
            app.ActionPropertiesLabel_2 = uilabel(app.ActionsTab);
            app.ActionPropertiesLabel_2.Text = 'Action Properties';
            app.ActionMethodsLabel = uilabel(app.ActionsTab);
            app.ActionMethodsLabel.Text = 'Action Methods';
            app.btn_EditActionClass = uibutton(app.ActionsTab, 'push');
            app.btn_EditActionClass.ButtonPushedFcn = createCallbackFcn(app, @btn_EditActionClassButtonPushed, true);
            app.btn_EditActionClass.Text = 'Edit Action Class';
            app.btn_refreshActionClasses = uibutton(app.ActionsTab, 'push');
            app.btn_refreshActionClasses.Icon = 'icons8-refresh-24.png';
            app.btn_refreshActionClasses.Tooltip = {'Refresh the content in this tab'};
            app.btn_refreshActionClasses.Text = '';
            app.txt_ActionPropHelp = uitextarea(app.ActionsTab);
            app.txt_ActionPropHelp.Editable = 'off';
            app.txt_ActionMethodHelp = uitextarea(app.ActionsTab);
            app.txt_ActionMethodHelp.Editable = 'off';
            app.CenterPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.CenterPanel);
            title(app.UIAxes, 'Environment')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.RightPanel = uipanel(app.GridLayout);
            % Show the figure after all components are created
            app.URBANUTMUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = urban_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.URBANUTMUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.URBANUTMUIFigure)
        end
    end
end